classdef DPS1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadDataButton              matlab.ui.control.Button
        DYNAMICPRICINGSYSTEMLabel   matlab.ui.control.Label
        retail_priceLabel           matlab.ui.control.Label
        Predict_retail_priceButton  matlab.ui.control.Button
        dateDatePicker              matlab.ui.control.DatePicker
        dateDatePickerLabel         matlab.ui.control.Label
        categoryDropDown            matlab.ui.control.DropDown
        categoryDropDownLabel       matlab.ui.control.Label
        varietyDropDown             matlab.ui.control.DropDown
        varietyDropDownLabel        matlab.ui.control.Label
        centreDropDown              matlab.ui.control.DropDown
        centreDropDownLabel         matlab.ui.control.Label
        unitDropDown                matlab.ui.control.DropDown
        unitDropDownLabel           matlab.ui.control.Label
        commodityDropDown           matlab.ui.control.DropDown
        commodityDropDownLabel      matlab.ui.control.Label
        stateDropDown               matlab.ui.control.DropDown
        stateDropDownLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Predict_retail_priceButton
        function Predict_retail_priceButtonPushed(app, event)
            state = string(app.stateDropDown.Value);
            centre = string(app.centreDropDown.Value);
            commodity = string(app.commodityDropDown.Value);
            variety = string(app.varietyDropDown.Value);
            unit = string(app.unitDropDown.Value);
            category = string(app.categoryDropDown.Value);

            dt = app.dateDatePicker.Value;

            Day = day(dt);
            Month = month(dt);
            Year = year(dt);

            M = load("dps.mat");

            T = table(state, centre, commodity, variety, unit, category, Day, Month, Year, ...
                'VariableNames', {'state','centre','commodity','variety','unit','category','Day','Month','Year'});

            yfit = predict(M.DPSmodel, T);

            app.retail_priceLabel.Text = ['Predicted Retail Price: ₹ ' num2str(yfit)];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            opts = detectImportOptions('DPS(f).csv');
            opts = setvaropts(opts,'date','InputFormat','dd/MM/yyyy');

            data = readtable('DPS(f).csv',opts);

            app.stateDropDown.Items = cellstr(unique(string(data.state)));
            app.centreDropDown.Items = cellstr(unique(string(data.centre)));
            app.commodityDropDown.Items = cellstr(unique(string(data.commodity)));
            app.varietyDropDown.Items = cellstr(unique(string(data.variety)));
            app.unitDropDown.Items = cellstr(unique(string(data.unit)));
            app.categoryDropDown.Items = cellstr(unique(string(data.category)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.stateDropDownLabel = uilabel(app.UIFigure);
            app.stateDropDownLabel.Text = 'state';
            app.stateDropDown = uidropdown(app.UIFigure);
            app.commodityDropDownLabel = uilabel(app.UIFigure);
            app.commodityDropDownLabel.Text = 'commodity';
            app.commodityDropDown = uidropdown(app.UIFigure);
            app.unitDropDownLabel = uilabel(app.UIFigure);
            app.unitDropDownLabel.Text = 'unit';
            app.unitDropDown = uidropdown(app.UIFigure);
            app.centreDropDownLabel = uilabel(app.UIFigure);
            app.centreDropDownLabel.Text = 'centre';
            app.centreDropDown = uidropdown(app.UIFigure);
            app.varietyDropDownLabel = uilabel(app.UIFigure);
            app.varietyDropDownLabel.Text = 'variety';
            app.varietyDropDown = uidropdown(app.UIFigure);
            app.categoryDropDownLabel = uilabel(app.UIFigure);
            app.categoryDropDownLabel.Text = 'category';
            app.categoryDropDown = uidropdown(app.UIFigure);
            app.dateDatePickerLabel = uilabel(app.UIFigure);
            app.dateDatePickerLabel.Text = 'date';
            app.dateDatePicker = uidatepicker(app.UIFigure);
            app.Predict_retail_priceButton = uibutton(app.UIFigure, 'push');
            app.Predict_retail_priceButton.ButtonPushedFcn = createCallbackFcn(app, @Predict_retail_priceButtonPushed, true);
            app.Predict_retail_priceButton.Text = 'Predict_retail_price';
            app.retail_priceLabel = uilabel(app.UIFigure);
            app.retail_priceLabel.Text = 'retail_price';
            app.DYNAMICPRICINGSYSTEMLabel = uilabel(app.UIFigure);
            app.DYNAMICPRICINGSYSTEMLabel.Text = '🏷️DYNAMIC PRICING SYSTEM ';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'LoadDataButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPS1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end